classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BaseLabel          matlab.ui.control.Label
        ExponentLabel      matlab.ui.control.Label
        PhaseSliderLabel   matlab.ui.control.Label
        PhaseSlider        matlab.ui.control.Slider
        WeightSliderLabel  matlab.ui.control.Label
        WeightSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            global gcolor;
            % Define theta. Get a and b.
            theta = 0:pi/1800:2*pi;
            a = app.PhaseSlider.Value;
            b = app.WeightSlider.Value;
            load('results\summary.mat');
            modes = unique(D.modes);
            for i = 1:length(modes)
                g = colormod(i);
                z = D.zP2P(i);
                magnitude = abs(z);
                thet = (angle(z));
                plotline = polarplot(app.Pax, thet, magnitude, 'o');
                %hold on
                %arrowpolarplot(vector, gcolor(g,:));
            end
            % Calculate r and plot it
            r = (1 + sin(a.*theta)).^(1/b);
            %plotline = polarplot(app.Pax, theta, r);
            %legend show
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)
            % Replace 0 in equation with unicode theta
            app.BaseLabel.Text = ['r = ( 1 + sin(a' char(00952) ') )'];
            
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
            
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: PhaseSlider, WeightSlider
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'r = ( 1 + sin(a0) )';
            app.ExponentLabel = uilabel(app.UIFigure);
            app.ExponentLabel.Text = '1/b';
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [0 360];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.WeightSliderLabel = uilabel(app.UIFigure);
            app.WeightSliderLabel.Text = 'Weight';
            app.WeightSlider = uislider(app.UIFigure);
            app.WeightSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end